classdef audiosettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DeviceOutputDropDownLabel  matlab.ui.control.Label
        DeviceInputDropDownLabel   matlab.ui.control.Label
        AudioDriverDropDown        matlab.ui.control.DropDown
        AudioDriverDropDownLabel   matlab.ui.control.Label
        DeviceInputDropDown        matlab.ui.control.DropDown
        DeviceOutputDropDown       matlab.ui.control.DropDown
        SampleRateDropDown         matlab.ui.control.DropDown
        BufferSizeDropDown         matlab.ui.control.DropDown
        AsioSettingsButton         matlab.ui.control.Button
        LatencyTestButton          matlab.ui.control.Button
        latencyMS                  matlab.ui.control.TextArea
        BufferSizeDropDownLabel    matlab.ui.control.Label
        SampleRateDropDownLabel    matlab.ui.control.Label
    end

    %{
    
    
    %}

    properties (Access = private)
        hostApp 
        audioDeviceChange
    end


    methods (Access = private)

        function [inputs,outputs] = getDeviceIO(app)

            allDevices = audiostreamer.getAudioDevices;

            % make empty arrays for audio inputs and outputs
            inputs = {};
            outputs = {};
            
            % loop over all audio inputs and outputs and add to array if
            % corresponds to active driver
            for i=1:numel(allDevices)
                if allDevices(i).Driver == app.hostApp.audioDevice 
                    if allDevices(i).MaxRecorderChannels > 0
                        inputs{end+1} = allDevices(i).Name;
                    end
                    
                    if allDevices(i).MaxPlayerChannels > 0
                        outputs{end+1} = allDevices(i).Name;
                    end
                end
            end
            
            % convert array to array type UI likes
            inputs = cell2mat(inputs);
            outputs = cell2mat(outputs);
        end
      

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hostApp)
            % get audio host
            app.hostApp = hostApp;
            
            % setup device options
            app.AudioDriverDropDown.Items = audiostreamer.getDrivers;

            % set sample rate and buffer size menu to value
            app.SampleRateDropDown.Value = string(app.hostApp.samplerate);
            app.BufferSizeDropDown.Value = string(app.hostApp.bufferSize);
            
            % device option callback to update IO options
            app.AudioDriverDropDownValueChanged;
        end

        % Value changed function: AudioDriverDropDown
        function AudioDriverDropDownValueChanged(app, event)
            % Change the input and output devices first so it doesn't error
            if app.AudioDriverDropDown.Value ~= app.hostApp.audioDevice

                app.hostApp.audioDeviceOutput = app.DeviceOutputDropDown.Value;
                app.hostApp.audioDeviceInput = app.DeviceInputDropDown.Value;

                try
                    app.hostApp.audioStreamer.Player = app.hostApp.audioDeviceOutput;
                    app.hostApp.audioStreamer.Recorder = app.hostApp.audioDeviceInput;
                catch
                    
                end
            end
            app.hostApp.audioDevice = app.AudioDriverDropDown.Value;
                % change driver here instead
            app.hostApp.audioStreamer.Driver = app.hostApp.audioDevice;
         
            % enable asio settings button if using asio
            if app.hostApp.audioDevice == "ASIO"
                app.AsioSettingsButton.Enable = "on";

            else 
                app.AsioSettingsButton.Enable = "off";

            end

            % change audio device input and output options based on device
            [inputs, outputs] = getDeviceIO(app);
            app.DeviceInputDropDown.Items = inputs;
            app.DeviceOutputDropDown.Items = outputs;

        end

        % Value changed function: DeviceInputDropDown
        function DeviceInputDropDownValueChanged(app, event)
           
            app.hostApp.audioDeviceInput = app.DeviceInputDropDown.Value;
            app.hostApp.audioStreamer.Recorder = app.hostApp.audioDeviceInput;
     
        end

        % Value changed function: DeviceOutputDropDown
        function DeviceOutputDropDownValueChanged(app, event)
            app.hostApp.audioDeviceOutput = app.DeviceOutputDropDown.Value;
            app.hostApp.audioStreamer.Player = app.hostApp.audioDeviceOutput;

     
        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)

            app.hostApp.samplerate = str2double(app.SampleRateDropDown.Value);
            app.hostApp.audioStreamer.SampleRate = app.hostApp.samplerate;

        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            
            app.hostApp.bufferSize = str2double(app.BufferSizeDropDown.Value);
            app.hostApp.audioStreamer.DeviceBufferSize = app.hostApp.bufferSize;

        end

        % Button pushed function: LatencyTestButton
        function LatencyTestButtonPushed(app, event)
            app.hostApp.audioStreamer.RecorderChannels = 1;
            app.hostApp.audioStreamer.PlayerChannels = 1;

            latency = measureLoopbackLatency(app.hostApp.audioStreamer);
            latencyMs = round(1000*latency/app.hostApp.samplerate, 1);
            latencyText = strcat(string(latencyMs)," ms (",string(latency)," samples)");
            app.latencyMS.Value = latencyText;

            app.hostApp.audioStreamer.PlayerChannels = 'auto';
        end

        % Button pushed function: AsioSettingsButton
        function AsioSettingsButtonPushed(app, event)
            if app.hostApp.audioDevice == "ASIO"
                asiosettings
            end
        end

        % Callback function: not associated with a component
        function DeviceOutputDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SampleRateDropDownLabel = uilabel(app.UIFigure);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.BufferSizeDropDownLabel = uilabel(app.UIFigure);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.latencyMS = uitextarea(app.UIFigure);
            app.latencyMS.Editable = 'off';
            app.LatencyTestButton = uibutton(app.UIFigure, 'push');
            app.LatencyTestButton.ButtonPushedFcn = createCallbackFcn(app, @LatencyTestButtonPushed, true);
            app.LatencyTestButton.Text = 'Latency Test';
            app.AsioSettingsButton = uibutton(app.UIFigure, 'push');
            app.AsioSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AsioSettingsButtonPushed, true);
            app.AsioSettingsButton.Enable = 'off';
            app.AsioSettingsButton.Text = 'ASIO Settings';
            app.BufferSizeDropDown = uidropdown(app.UIFigure);
            app.BufferSizeDropDown.Items = {'32', '64', '128', '256', '512', '1024'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Value = '512';
            app.SampleRateDropDown = uidropdown(app.UIFigure);
            app.SampleRateDropDown.Items = {'44.1 kHz', '48.0 kHz'};
            app.SampleRateDropDown.ItemsData = {'44100', '48000'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Value = '48000';
            app.DeviceOutputDropDown = uidropdown(app.UIFigure);
            app.DeviceOutputDropDown.Items = {};
            app.DeviceOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceOutputDropDownValueChanged, true);
            app.DeviceOutputDropDown.Placeholder = 'Select';
            app.DeviceOutputDropDown.Value = {};
            app.DeviceInputDropDown = uidropdown(app.UIFigure);
            app.DeviceInputDropDown.Items = {};
            app.DeviceInputDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceInputDropDownValueChanged, true);
            app.DeviceInputDropDown.Placeholder = 'Select';
            app.DeviceInputDropDown.Value = {};
            app.AudioDriverDropDownLabel = uilabel(app.UIFigure);
            app.AudioDriverDropDownLabel.Text = 'Audio Driver';
            app.AudioDriverDropDown = uidropdown(app.UIFigure);
            app.AudioDriverDropDown.Items = {};
            app.AudioDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDriverDropDownValueChanged, true);
            app.AudioDriverDropDown.Placeholder = 'Select';
            app.AudioDriverDropDown.Value = {};
            app.DeviceInputDropDownLabel = uilabel(app.UIFigure);
            app.DeviceInputDropDownLabel.Text = 'Device Input';
            app.DeviceOutputDropDownLabel = uilabel(app.UIFigure);
            app.DeviceOutputDropDownLabel.Text = 'Device Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiosettings(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end